classdef XCorr_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DetectDelayButton     matlab.ui.control.Button
        SampleDelaybetweenChannelsGaugeLabel  matlab.ui.control.Label
        SampleDelaybetweenChannelsGauge  matlab.ui.control.LinearGauge
        Label                 matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
        TabGroup              matlab.ui.container.TabGroup
        ShiftedStereoTab      matlab.ui.container.Tab
        ChooseAudioButton     matlab.ui.control.Button
        SelectaSTEREOtrackwithleftandrightchannelrelativelydelayedLabel  matlab.ui.control.Label
        ShiftedMonoTab        matlab.ui.container.Tab
        Label_4               matlab.ui.control.Label
        SelectTrack1Button    matlab.ui.control.Button
        SelectTrack2Button    matlab.ui.control.Button
        Lamp                  matlab.ui.control.Lamp
        Lamp_2                matlab.ui.control.Lamp
        ClearSelectionButton  matlab.ui.control.Button
        PlayAudioButton       matlab.ui.control.Button
        AlignButton           matlab.ui.control.StateButton
        ExportButton          matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y = 0; % Audio
        Nsamples; % Audio Sample length
        fs=0; % Sampling rate
        selected = [0, 0];
        delay;
        tail;
        originalDelay = 0;
    end
    
    methods (Access = private)
        
        
        function reset(app)
            
            app.y = 0;
            app.fs = 0;
            app.Nsamples = 0;
            app.selected = [0,0];
            app.delay = 0;
            app.originalDelay = 0;
            app.tail = 0;
            
            app.Lamp.Color = 'red';
            app.Lamp_2.Color = 'red';
            
            app.TextArea.Value = "";
            plot(0,0, 'Parent', app.UIAxes);
            plot(0,0, 'Parent', app.UIAxes2);
            app.Label.Text = "";
            app.SampleDelaybetweenChannelsGauge.Value=app.delay;
            
            app.AlignButton.Enable = 0;
            app.PlayAudioButton.Enable = 0;
            app.AlignButton.Text = "Align";
            app.AlignButton.BackgroundColor = [0.96,0.96,0.96];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AlignButton.Enable = 0;
            app.PlayAudioButton.Enable = 0;
        end

        % Button pushed function: ChooseAudioButton
        function ChooseAudioButtonPushed(app, event)
          
            [file,path] = uigetfile('*.wav');
            
            if(app.selected ~= [0,0])
                reset(app);
            end
            
            [app.y,app.fs] = audioread(fullfile(path,file));
            
            app.Nsamples = size(app.y,1);
            
            if(size(app.y,2) ~= 2)
                fig = uifigure;
                uialert(fig,'Audio must be in stereo','Invalid File');
                app.y = 0;
            else
                txt = sprintf("Sample rate: %d", app.fs);
                app.TextArea.Value = append("Audio: ", file, newline, txt);
                
                app.selected = [1,1];
                app.Lamp.Color = 'green';
                app.Lamp_2.Color = 'green';
                
                plot((1:app.Nsamples)/app.fs,app.y,'Parent', app.UIAxes)
            end
        end

        % Button pushed function: DetectDelayButton
        function DetectDelayButtonPushed(app, event)
            
            if(size(app.y, 2)==2)
                r1 = xcorr(app.y(:,1), app.y(:,2)); %Cross correlation
                app.delay = find(r1==max(r1))-app.Nsamples;
                
                app.SampleDelaybetweenChannelsGauge.Value=app.delay;
                %text = sprintf('%d Samples', delay1);
                app.Label.Text = sprintf('%d Samples', app.delay);
                
                power = rms(app.y,1);
                A = min(power)/max(power);
                
                freq = (0.001:0.001:1)*app.fs/2;
                mag = 1+A*sin(pi/2 + 2*pi*freq*(app.delay/app.fs));
                
                plot(freq, mag, 'Parent', app.UIAxes2)
                
                app.AlignButton.Enable = 1;
                app.PlayAudioButton.Enable = 1;
                
            end
        end

        % Button pushed function: SelectTrack1Button
        function SelectTrack1ButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            
            [trk,f1] = audioread(fullfile(path,file));
            
            
            if(app.selected(1)==1)
                fig = uifigure;
                uialert(fig,'Selection already made for track 1','Error');
            elseif(size(trk,2) ~= 1)
                fig = uifigure;
                uialert(fig,'Audio must be mono track','Invalid File');
                
            elseif(app.fs ~= 0 && app.fs ~= f1)
                fig = uifigure;
                uialert(fig,'Sampling frequencys of tracks must be equal','Invalid File');
            else
                if (app.y==0)
                    app.y = trk;
                    app.Nsamples = size(trk,1);
                    app.fs = f1;
                    
                    app.selected(1) = 1;
                    app.Lamp.Color = 'green';
                    
                    app.TextArea.Value = append("Track 1: ", file);
                    
                else
                    
                    if (size(trk,1)>=app.Nsamples)
                       
                        app.y = cat(2,trk(1:app.Nsamples), app.y);
                    else
                        app.y = cat(2,trk, app.y(1:size(trk,1)));
                        app.Nsamples = size(trk,1);
                    end
                    
                    app.selected(1) = 1;
                    app.Lamp.Color = 'green';
                    
                    txt = sprintf("Sample rate: %d", app.fs);
                    app.TextArea.Value = append( app.TextArea.Value, newline, "Track 1: ", file, newline, txt);
                end
                
                plot((1:app.Nsamples)/app.fs,app.y,'Parent', app.UIAxes)
            end
        end

        % Button pushed function: SelectTrack2Button
        function SelectTrack2ButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            
            [trk,f1] = audioread(fullfile(path,file));
            
            if(app.selected(2)==1)
                fig = uifigure;
                uialert(fig,'Selection already made for track 2','Error');
                
            elseif(size(trk,2) ~= 1)
                fig = uifigure;
                uialert(fig,'Audio must be mono track','Invalid File');
                
            elseif(app.fs ~= 0 && app.fs ~= f1)
                fig = uifigure;
                uialert(fig,'Sampling frequencys of tracks must be equal','Invalid File');
            else
                if (app.y==0)
                    app.y = trk;
                    app.Nsamples = size(trk,1);
                    app.fs = f1;
                    
                    app.selected(2) = 1;
                    app.Lamp_2.Color = 'green';
                    
                    app.TextArea.Value = append("Track 2: ", file);
                    
                else
                    
                    if (size(trk,1)>=app.Nsamples)
                       
                        app.y = cat(2, app.y, trk(1:app.Nsamples));
                    else
                        app.y = cat(2, app.y(1:size(trk,1)), trk);
                        app.Nsamples = size(trk,1);
                    end
                    
                    app.selected(2) = 1;
                    app.Lamp_2.Color = 'green';
                    
                    txt = sprintf("Sample rate: %dHz", app.fs);
                    app.TextArea.Value = append( app.TextArea.Value, newline, "Track 2: ", file, newline, txt);
                end
                
                plot((1:app.Nsamples)/app.fs,app.y,'Parent', app.UIAxes)
            end
        end

        % Button pushed function: ClearSelectionButton
        function ClearSelectionButtonPushed(app, event)
            reset(app);
        end

        % Value changed function: AlignButton
        function AlignButtonValueChanged(app, event)
            align = app.AlignButton.Value;
            
            if(app.originalDelay == 0)
                app.originalDelay = app.delay;
            end
            
            if (align == 1)
                if(app.originalDelay>=0)
                    app.AlignButton.BackgroundColor = [0.22,0.98,0.22];
                    app.AlignButton.Text = "Aligned";
                    
                    app.tail = app.y(app.Nsamples-app.originalDelay+1:app.Nsamples, 2);
                    app.y(:,2)=[zeros(app.originalDelay,1); app.y(1:app.Nsamples-app.originalDelay, 2)];   
                else
                    app.AlignButton.BackgroundColor = [0.22,0.98,0.22];
                    app.AlignButton.Text = "Aligned";
                    
                    app.tail = app.y(app.Nsamples-abs(app.originalDelay)+1:app.Nsamples, 1);
                    app.y(:,1)=[zeros(abs(app.originalDelay),1); app.y(1:app.Nsamples-abs(app.originalDelay), 1)];
                end
            else
                if(app.originalDelay>=0)
                    app.AlignButton.BackgroundColor = [0.98, 0.22,0.22];
                    app.AlignButton.Text = "Unaligned";
                    
                    app.y(:,2)=[app.y(app.originalDelay+1:app.Nsamples, 2); app.tail];
                    app.tail = 0;
                else
                    app.AlignButton.BackgroundColor = [0.98, 0.22,0.22];
                    app.AlignButton.Text = "Unaligned";
                    
                    app.y(:,1)=[app.y(abs(app.originalDelay)+1:app.Nsamples, 1); app.tail];
                    app.tail = 0;
                end
            end
            
            plot((1:app.Nsamples)/app.fs,app.y,'Parent', app.UIAxes)
  
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            
            if(app.Nsamples/app.fs<10)
                
                sound = (app.y(:,1) + app.y(:,2))/2;
                soundsc(sound,app.fs);
            else
                sound = (app.y(1:10*app.fs,1) + app.y(1:10*app.fs,2))/2;
                soundsc(sound,app.fs);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DetectDelayButton = uibutton(app.UIFigure, 'push');
            app.DetectDelayButton.ButtonPushedFcn = createCallbackFcn(app, @DetectDelayButtonPushed, true);
            app.DetectDelayButton.Text = 'Detect Delay';
            app.SampleDelaybetweenChannelsGaugeLabel = uilabel(app.UIFigure);
            app.SampleDelaybetweenChannelsGaugeLabel.Text = 'Sample Delay between Channels';
            app.SampleDelaybetweenChannelsGauge = uigauge(app.UIFigure, 'linear');
            app.SampleDelaybetweenChannelsGauge.Limits = [-1024 1024];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ShiftedStereoTab = uitab(app.TabGroup);
            app.ShiftedStereoTab.Title = 'Shifted Stereo';
            app.ChooseAudioButton = uibutton(app.ShiftedStereoTab, 'push');
            app.ChooseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseAudioButtonPushed, true);
            app.ChooseAudioButton.Text = 'Choose Audio';
            app.SelectaSTEREOtrackwithleftandrightchannelrelativelydelayedLabel = uilabel(app.ShiftedStereoTab);
            app.SelectaSTEREOtrackwithleftandrightchannelrelativelydelayedLabel.Text = {'Select a STEREO track with left'; 'and right channel relatively delayed'};
            app.ShiftedMonoTab = uitab(app.TabGroup);
            app.ShiftedMonoTab.Title = 'Shifted Mono';
            app.Label_4 = uilabel(app.ShiftedMonoTab);
            app.Label_4.Text = 'Select 2 MONO tracks relatively delayed';
            app.SelectTrack1Button = uibutton(app.ShiftedMonoTab, 'push');
            app.SelectTrack1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectTrack1ButtonPushed, true);
            app.SelectTrack1Button.Text = 'Select Track 1';
            app.SelectTrack2Button = uibutton(app.ShiftedMonoTab, 'push');
            app.SelectTrack2Button.ButtonPushedFcn = createCallbackFcn(app, @SelectTrack2ButtonPushed, true);
            app.SelectTrack2Button.Text = 'Select Track 2';
            app.Lamp = uilamp(app.ShiftedMonoTab);
            app.Lamp_2 = uilamp(app.ShiftedMonoTab);
            app.ClearSelectionButton = uibutton(app.ShiftedMonoTab, 'push');
            app.ClearSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSelectionButtonPushed, true);
            app.ClearSelectionButton.Text = {'Clear'; 'Selection'};
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.AlignButton = uibutton(app.UIFigure, 'state');
            app.AlignButton.ValueChangedFcn = createCallbackFcn(app, @AlignButtonValueChanged, true);
            app.AlignButton.Text = 'Align';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'Time (s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Predicted Comb Filtering')
            xlabel(app.UIAxes2, 'Frequency (hz)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [20 20000];
            app.UIAxes2.YLim = [0.05 2];
            app.UIAxes2.XTick = [63 125 250 500 1000 2000 4000 8000 16000];
            app.UIAxes2.XScale = 'log';
            app.UIAxes2.XTickLabel = {'63'; '125'; '250'; '500'; '1k'; '2k'; '4k'; '8k'; '16k'};
            app.UIAxes2.YTick = [0.125 0.25 0.5 1 2];
            app.UIAxes2.YScale = 'log';
            app.UIAxes2.YTickLabel = {'-18dB'; '-12dB'; '-6dB'; '0dB'; '6dB'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XCorr_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end